classdef Covid_Data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryLabel               matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Value
        WorldData 

        dateCellArray % array of dates

        ActualState
        ActualCountry

        TotalDays
        NofCountries

        AllCountryNames
        AllStatesNames

        ActualCumulativeData
        ActualDailyData

        AveragedActualCumulativeData
        AveragedActualDailyData
        AveragingWindow

        Averages

        %boolean
        ShowDeaths
        ShowCases

        ShowCumulative

    end
    
    methods (Access = public)
        function GetData(app)
            app.AllCountryNames = getAllCountriesNames(app.WorldData);
            app.NofCountries = size(app.AllCountryNames, 2);
            app.AllCountryNames = ["Global", app.AllCountryNames]; 

            if strcmp(app.ActualCountry, "Global")

                app.AllStatesNames = "All";
                app.ActualState = "All";

                app.ActualCumulativeData = cell2mat(getAllCumulativeData(app.AllCountryNames(1, 2), app.ActualState, app.WorldData));
                for nav = 3:app.NofCountries
                    app.ActualCumulativeData = app.ActualCumulativeData + cell2mat(getAllCumulativeData(app.AllCountryNames(1, nav), app.ActualState, app.WorldData));
                end

            else
 
                app.AllStatesNames = getAllStatesNames(app.ActualCountry, app.WorldData);
                app.ActualCumulativeData = cell2mat(getAllCumulativeData(app.ActualCountry, app.ActualState, app.WorldData));

            end

            app.TotalDays = size(app.ActualCumulativeData, 1);

            app.ActualDailyData = zeros(size(app.ActualCumulativeData));

            for nav = 1: app.TotalDays %loops to prevent negative values from beeing plotted
                if nav == 1
                    app.ActualDailyData (1, :) = app.ActualCumulativeData(1,:);
                else
                    app.ActualDailyData(nav,:) = app.ActualCumulativeData(nav,:) - app.ActualCumulativeData(nav-1,:);
                    if app.ActualDailyData(nav,1) < 0 
                        app.ActualDailyData(nav,1) = 0;
                    end
                    if app.ActualDailyData(nav,2) < 0 
                        app.ActualDailyData(nav,2) = 0;
                    end                    
                end
            end
        end

        function PlotData(app)
            dn = datenum(string(app.dateCellArray), 2);   

            xlim(app.UIAxes,'manual');
            xlim(app.UIAxes,[dn(1,1) dn(end,1)])
            xData = linspace(dn(1,1), dn(end,1), 10);

            ylim(app.UIAxes,'auto');
            grid(app.UIAxes,'on');

            xticks(app.UIAxes,'manual');
            xticks(app.UIAxes, xData);

            yyaxis(app.UIAxes, "left");
            cla(app.UIAxes);
            yyaxis(app.UIAxes, "right");
            cla(app.UIAxes);

            if app.ShowCumulative
                if app.ShowCases && ~app.ShowDeaths
                    yyaxis(app.UIAxes, "left");
                    bar(app.UIAxes,dn,app.AveragedActualCumulativeData(:,1),'LineWidth',1)
                end
                if app.ShowDeaths && ~app.ShowCases
                    yyaxis(app.UIAxes, "right");
                    plot(app.UIAxes,dn,app.AveragedActualCumulativeData(:,2)) 
                end
                if app.ShowDeaths && app.ShowCases
                    yyaxis(app.UIAxes, "right");
                    plot(app.UIAxes,dn,app.AveragedActualCumulativeData(:,2))  
                    yyaxis(app.UIAxes, "left");
                    bar(app.UIAxes,dn,app.AveragedActualCumulativeData(:,1),'LineWidth',1)               
                end                
            else
                if app.ShowCases && ~app.ShowDeaths
                    yyaxis(app.UIAxes, "left");
                    bar(app.UIAxes,dn,app.AveragedActualDailyData(:,1),'LineWidth',1)               
                elseif app.ShowDeaths && ~app.ShowCases
                    yyaxis(app.UIAxes, "right");
                    plot(app.UIAxes,dn,app.AveragedActualDailyData(:,2))                
                else
                    yyaxis(app.UIAxes, "right");
                    plot(app.UIAxes,dn,app.AveragedActualDailyData(:,2))    
                    yyaxis(app.UIAxes, "left");
                    bar(app.UIAxes,dn,app.AveragedActualDailyData(:,1),'LineWidth',1)                
                end
            end

            datetick(app.UIAxes,'x', 6, 'keepticks');
            app.RefreshTitle();

        end

        function ComputeAverages(app)
            
            app.AveragedActualCumulativeData(:,2) = movmean(app.ActualCumulativeData(:,2),[app.AveragingWindow-1 0]);
            app.AveragedActualCumulativeData(:,1) = movmean(app.ActualCumulativeData(:,1),[app.AveragingWindow-1 0]);

            app.AveragedActualDailyData(:,2) = movmean(app.ActualDailyData(:,2),[app.AveragingWindow-1 0]);
            app.AveragedActualDailyData(:,1) = movmean(app.ActualDailyData(:,1),[app.AveragingWindow-1 0]);

        end

        function RefreshTitle(app)
    
            if app.AveragingWindow == 1
                averagingWindowInfo = '';
            else
                averagingWindowInfo = sprintf(' (%d - day mean)', app.AveragingWindow);
            end

            if strcmp(app.ActualState, 'All')
                if strcmp(app.ActualCountry, 'Global')
                    displayInfo = 'the World';
                else
                    displayInfo = app.ActualCountry;
                end
            else
                displayInfo = app.ActualState;                
            end

            if app.ShowCumulative
                if app.ShowCases && ~app.ShowDeaths
                    title(app.UIAxes, sprintf('Cumulative Number of Cases in %s %s', displayInfo, averagingWindowInfo));
                end
                if app.ShowDeaths && ~app.ShowCases
                    title(app.UIAxes, sprintf('Cumulative Number of Deaths in %s %s', displayInfo, averagingWindowInfo));
                end
                if app.ShowDeaths && app.ShowCases
                    title(app.UIAxes, sprintf('Cumulative Number of Cases and Deaths in %s %s', displayInfo, averagingWindowInfo));
                end                
            else
                if app.ShowCases && ~app.ShowDeaths
                    title(app.UIAxes, sprintf('Daily Number of Cases in %s %s', displayInfo, averagingWindowInfo));
                elseif app.ShowDeaths && ~app.ShowCases
                    title(app.UIAxes, sprintf('Daily Number of Deaths in %s %s', displayInfo, averagingWindowInfo));
                else
                    title(app.UIAxes, sprintf('Daily Number of Cases and Deaths in %s %s', displayInfo, averagingWindowInfo));
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data
            app.WorldData = GlobalData("World", covid_data);
            s = size(covid_data);
            app.dateCellArray = cell(s(2)-2, 1);
            app.dateCellArray(1:(s(2)-2),1) = covid_data(1,3:end);
            app.ActualCountry = "Global";
            app.ActualState = "All";
            app.ShowCases = true;
            app.ShowDeaths = true;
            app.ShowCumulative = true;
            app.AveragedofdaysSlider.Value = 1;
            app.AveragingWindow = app.AveragedofdaysSlider.Value;
            app.ShowCumulative = true;
            app.ShowCases = true;
            app.ShowDeaths = false;
       
            app.CountryListBox.Items = {'Global'};
            app.CountryListBox.Value = {'Global'};
            app.StateorRegionListBox.Items = {'All'};
            app.StateorRegionListBox.Value = {'All'};
            app.GetData();
            app.ComputeAverages();
            app.PlotData();
           
            app.CountryListBox.Items = app.AllCountryNames;
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.ActualCountry = app.CountryListBox.Value;
            app.ActualState = "All";
            app.GetData();
            app.ComputeAverages();
            app.PlotData();
            app.StateorRegionListBox.Items = app.AllStatesNames;
            % selected value changed event
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.ActualState = app.StateorRegionListBox.Value;
            app.GetData();
            app.ComputeAverages();
            app.PlotData();
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.AveragingWindow = round(app.AveragedofdaysSlider.Value);
            app.ComputeAverages();
            app.PlotData();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            if strcmp("Cumulative", app.OptionButtonGroup.SelectedObject.Text)
                app.ShowCumulative = true;
            else
                app.ShowCumulative = false;
            end

            app.PlotData();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatoPlotButtonGroup.SelectedObject.Text;

            switch selectedButton
                case 'Cases'
                    app.ShowCases = true;
                    app.ShowDeaths = false;

                case 'Deaths'
                    app.ShowCases = false;
                    app.ShowDeaths = true;
                    
                case 'Both'
                    app.ShowCases = true;
                    app.ShowDeaths = true;
            end
            
            app.PlotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'COVID-19 Data Visualization (Data source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = {};
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofdaysSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid_Data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end